classdef logpose_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        HOMETab                 matlab.ui.container.Tab
        run_IQ_button           matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        UPLOADIQFILEButton      matlab.ui.control.Button
        ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel  matlab.ui.control.Label
        StartbyuploadingaIQfileontherightLabel  matlab.ui.control.Label
        WELCOMETOLOGPOSELabel   matlab.ui.control.Label
        IQTab                   matlab.ui.container.Tab
        run_acquisition_button  matlab.ui.control.Image
        IQCharacteristicsLabel  matlab.ui.control.Label
        hist_I                  matlab.ui.control.UIAxes
        hist_Q                  matlab.ui.control.UIAxes
        time_I                  matlab.ui.control.UIAxes
        time_Q                  matlab.ui.control.UIAxes
        iq_frq                  matlab.ui.control.UIAxes
        ACQUISITIONTab          matlab.ui.container.Tab
        run_tracking_button     matlab.ui.control.Image
        Best12SatelliteAcquisitionsLabel  matlab.ui.control.Label
        SatAcq_12               matlab.ui.control.UIAxes
        SatAcq_11               matlab.ui.control.UIAxes
        SatAcq_10               matlab.ui.control.UIAxes
        SatAcq_9                matlab.ui.control.UIAxes
        SatAcq_8                matlab.ui.control.UIAxes
        SatAcq_7                matlab.ui.control.UIAxes
        SatAcq_6                matlab.ui.control.UIAxes
        SatAcq_5                matlab.ui.control.UIAxes
        SatAcq_4                matlab.ui.control.UIAxes
        SatAcq_3                matlab.ui.control.UIAxes
        SatAcq_2                matlab.ui.control.UIAxes
        SatAcq                  matlab.ui.control.UIAxes
        TRACKINGTab             matlab.ui.container.Tab
        SATELLITEPOSTab         matlab.ui.container.Tab
        USERPOSTab              matlab.ui.container.Tab
    end

    properties (Access = public)
        nav; % NavTek class
        iq_file;
        acq_results_visual = cell(12);
        PRN_visual = cell(12);
        
        % Tracking Tab Components
        TRACKINGResults       matlab.ui.container.TabGroup
        track_tabs           matlab.ui.container.Tab
        CHANNELTITLELabel           matlab.ui.control.Label
        ViewResultsDropDown         matlab.ui.control.DropDown
        ViewResultsDropDownLabel    matlab.ui.control.Label
        CNo_Graph                   matlab.ui.control.UIAxes
        ViewResultsDropDown_2       matlab.ui.control.DropDown
        Correlation                 matlab.ui.control.UIAxes
        FilteredDLL                 matlab.ui.control.UIAxes
        RawDLL                      matlab.ui.control.UIAxes
        BitsNavMsg                  matlab.ui.control.UIAxes
        FilteredPLL                 matlab.ui.control.UIAxes
        RawPLL                      matlab.ui.control.UIAxes
        DistTimeScatter             matlab.ui.control.UIAxes

        track_visual =  struct();
        channel;
        fid;
        tracking_results;
        curr_i;
    end
   
    methods (Access = public)
       
        function probeData(app)
           
            %% Generate and plot IQ Characteristics
            app.fid = fopen(app.nav.fnameIQ, 'rb');

            if (app.fid > 0)
                %move to the start of the where we want to start post
                %processing
                fseek(app.fid, app.nav.skipNumBytes, 'bof');

                %find the number of samples per chipping sequence
                sampsPerCode = round(app.nav.fsamp / (app.nav.fchip / app.nav.codeLength));

                %read in the data 
                [data, count] = fread(app.fid, [1, 2 * 100 * sampsPerCode], app.nav.dataType);

                fclose(app.fid);

                if (count < 2 * 100 * sampsPerCode)
                    error("Error not enough data to load in");
                end

                %initialize plots
                timeScale = 0: 1/app.nav.fsamp : 5e-3; 
                
                %% Time domain plot
                data=data(1:2:end) + 1i .* data(2:2:end);                
                plot(app.time_I, 1000 * timeScale(1:round(sampsPerCode/2)), real(data(1:round(sampsPerCode/2)))); % Time Plot for I
                plot(app.time_Q, 1000 * timeScale(1:round(sampsPerCode/2)), imag(data(1:round(sampsPerCode/2)))); % Time Plot for Q

                %% Frequency domain plot
                [sigspec,freqv]=pwelch(data, 32768, 2048, 32768, app.nav.fsamp,'twosided');
                plot(app.iq_frq,([-(freqv(length(freqv)/2:-1:1));freqv(1:length(freqv)/2)])/1e6, 10*log10([sigspec(length(freqv)/2+1:end);
                sigspec(1:length(freqv)/2)]));

                %% Histogram
                histogram(app.hist_I,real(data), -128:128)
%                 dmax = max(abs(data)) + 1;
%                 axis tight;     adata = axis;
%                 axis(app.hist_I,[-dmax dmax adata(3) adata(4)]);
                
                histogram(app.hist_Q,imag(data), -128:128)
%                 dmax = max(abs(data)) + 1;
%                 axis tight;     adata = axis;
%                 axis(app.hist_Q,[-dmax dmax adata(3) adata(4)]);
               
            end
        end


        function satellite_acquired_generate(app)
            mesh(app.SatAcq,app.acq_results_visual{1});
%             grid on;
            title(app.SatAcq,strcat(['C/A for PRN ' int2str(app.PRN_visual{1})]))
            
            mesh(app.SatAcq_2,app.acq_results_visual{2});
%             grid on;
            title(app.SatAcq_2,strcat(['C/A for PRN ' int2str(app.PRN_visual{2})]))

            mesh(app.SatAcq_3,app.acq_results_visual{3});
%             grid on;
            title(app.SatAcq_3,strcat(['C/A for PRN ' int2str(app.PRN_visual{3})]))

            mesh(app.SatAcq_4,app.acq_results_visual{4});
%             grid on;
            title(app.SatAcq_4,strcat(['C/A for PRN ' int2str(app.PRN_visual{4})]))

            mesh(app.SatAcq_5,app.acq_results_visual{5});
%             grid on;
            title(app.SatAcq_5,strcat(['C/A for PRN ' int2str(app.PRN_visual{5})]))

            mesh(app.SatAcq_6,app.acq_results_visual{6});
%             grid on;
            title(app.SatAcq_6,strcat(['C/A for PRN ' int2str(app.PRN_visual{6})]))

            mesh(app.SatAcq_7,app.acq_results_visual{7});
%             grid on;
            title(app.SatAcq_7,strcat(['C/A for PRN ' int2str(app.PRN_visual{7})]))

            mesh(app.SatAcq_8,app.acq_results_visual{8});
%             grid on;
            title(app.SatAcq_8,strcat(['C/A for PRN ' int2str(app.PRN_visual{8})]))

            mesh(app.SatAcq_9,app.acq_results_visual{9});
%             grid on;
            title(app.SatAcq_9,strcat(['C/A for PRN ' int2str(app.PRN_visual{9})]))

            mesh(app.SatAcq_10,app.acq_results_visual{10});
%             grid on;
            title(app.SatAcq_10,strcat(['C/A for PRN ' int2str(app.PRN_visual{10})]))

            mesh(app.SatAcq_11,app.acq_results_visual{11});
%             grid on;
            title(app.SatAcq_11,strcat(['C/A for PRN ' int2str(app.PRN_visual{11})]))

            mesh(app.SatAcq_12,app.acq_results_visual{12});
%             grid on;
            title(app.SatAcq_12,strcat(['C/A for PRN ' int2str(app.PRN_visual{12})]))
        end

        function generate_tracking_results(app)
            app.TRACKINGResults = uitabgroup(app.TRACKINGTab);
            app.TRACKINGResults.TabLocation = 'bottom';
            app.TRACKINGResults.Position = [1 0 989 705];
            
            for i = 1:12
                % Creates Channel Tabs
                app.track_tabs = uitab(app.TRACKINGResults);
                app.track_tabs.Title = ['CH ' int2str(i)];
                app.track_tabs.BackgroundColor = [1 1 1];
                app.track_tabs.Scrollable = 'on';
                
                % Create CNo UIAXES
                app.CNo_Graph = uiaxes(app.track_tabs);
                title(app.CNo_Graph, 'CNo Estimation');
                ylabel(app.CNo_Graph, 'dB-Hz');
                xlabel(app.CNo_Graph, 'epoch computation');
                app.CNo_Graph.Position = [37 42 898 563];

                 % Create ViewResultsDropDown
                app.ViewResultsDropDown = uidropdown(app.track_tabs);
                app.ViewResultsDropDown.Items = {'Cno', 'Channel Results'};
                app.ViewResultsDropDown.FontWeight = 'bold';
                app.ViewResultsDropDown.Position = [830 647 131 22]; 
                app.ViewResultsDropDown.Value = 'Cno';
%                 app.ViewResultsDropDown.ValueChangedFcn = {@app.channelResultVisible,i};

                % Create ViewResultsDropDownLabel
                app.ViewResultsDropDownLabel = uilabel(app.track_tabs);
                app.ViewResultsDropDownLabel.HorizontalAlignment = 'right';
                app.ViewResultsDropDownLabel.FontWeight = 'bold';
                app.ViewResultsDropDownLabel.Position = [723 647 75 22];
                app.ViewResultsDropDownLabel.Text = 'View Results';
    
                % Create CHANNELTITLELabel
                app.CHANNELTITLELabel = uilabel(app.track_tabs);
                app.CHANNELTITLELabel.Position = [57 647 98 22];
                app.CHANNELTITLELabel.Text = ['CH ' int2str(i)];

                % ---------------------- Channel Results ----------------------
                % Create DistTimeScatter
                app.DistTimeScatter = uiaxes(app.track_tabs);
                title (app.DistTimeScatter, 'Discrete-Time Scatter Plot');
                xlabel(app.DistTimeScatter, 'I prompt');
                ylabel(app.DistTimeScatter, 'Q prompt');
                app.DistTimeScatter.Position = [37 426 269 173];
    
                % Create RawPLL
                app.RawPLL = uiaxes(app.track_tabs);
                title(app.RawPLL, 'Raw PLL discriminator');
                xlabel(app.RawPLL, 'Time (s)');
                ylabel(app.RawPLL, 'Amplitude');
                app.RawPLL.Position = [37 232 269 173];
    
                % Create FilteredPLL
                app.FilteredPLL = uiaxes(app.track_tabs);
                title(app.FilteredPLL, 'Title');
                xlabel(app.FilteredPLL, 'tight');
                app.FilteredPLL.Position = [37 27 269 173];
    
                % Create BitsNavMsg
                app.BitsNavMsg = uiaxes(app.track_tabs);
                title(app.BitsNavMsg, 'Bits of the navigation message');
                xlabel(app.BitsNavMsg, 'Time (s)');
                app.BitsNavMsg.Position = [355 426 580 206];
    
                % Create RawDLL
                app.RawDLL = uiaxes(app.track_tabs);
                title(app.RawDLL, 'Raw DLL discriminator');
                xlabel(app.RawDLL, 'Time (s)');
                ylabel(app.RawDLL, 'Amplitude');
                app.RawDLL.Position = [355 27 269 173];
    
                % Create FilteredDLL
                app.FilteredDLL = uiaxes(app.track_tabs);
                title(app.FilteredDLL, 'Filtered DLL discriminator');
                xlabel(app.FilteredDLL, 'Time (s)');
                ylabel(app.FilteredDLL, 'Amplitude');
                app.FilteredDLL.Position = [673 27 269 173];
    
                % Create Correlation
                app.Correlation = uiaxes(app.track_tabs);
                title(app.Correlation, 'Correlation results');
                xlabel(app.Correlation, 'Time (s)');
                app.Correlation.Position = [355 210 580 206];

                % Populate Channel 
                app.track_visual.tabname(i) = app.track_tabs;
                app.track_visual.CnoGraph(i) = app.CNo_Graph;
               
                %---------------- Store in track_visual struct ----------
                app.track_visual.DistGraph(i) = app.DistTimeScatter;
                app.track_visual.RawPLL(i) = app.RawPLL;
                app.track_visual.FilteredPLL(i) = app.FilteredPLL;
                app.track_visual.BitNavMsg(i) = app.BitsNavMsg;
                app.track_visual.RawDLL(i) = app.RawDLL;
                app.track_visual.FilteredDLL(i) = app.FilteredDLL;
                app.track_visual.Correlation(i) = app.Correlation;
                
                app.track_visual.DropDown(i) = app.ViewResultsDropDown;
                app.track_visual.DropDown(i).ValueChangedFcn = {@app.channelResultVisible,i};


            end
          
        end
    end

    methods (Access = private)
        function channelResultVisible(app, src,event,i)
            % Allow User to choose between CNo Graph or Channel Results
            value = app.track_visual.DropDown(i).Value; 
            switch value
                case 'Cno'
                    disp('on')
                    set(app.track_visual.CnoGraph(i), 'visible', 'on');

                    set(app.track_visual.DistGraph(i),'visible','off');
                    set(app.track_visual.RawPLL(i),'visible','off');
                    set(app.track_visual.FilteredPLL(i),'visible','off');
                    set(app.track_visual.BitNavMsg(i),'visible','off');
                    set(app.track_visual.RawDLL(i),'visible','off');
                    set(app.track_visual.FilteredDLL(i),'visible','off');
                    set(app.track_visual.Correlation(i),'visible','off');
                case 'Channel Results'
                    disp('off')
                    set(app.track_visual.CnoGraph(i), 'visible', 'off');

                    set(app.track_visual.DistGraph(i),'visible','on');
                    set(app.track_visual.RawPLL(i),'visible','on');
                    set(app.track_visual.FilteredPLL(i),'visible','on');
                    set(app.track_visual.BitNavMsg(i),'visible','on');
                    set(app.track_visual.RawDLL(i),'visible','on');
                    set(app.track_visual.FilteredDLL(i),'visible','on');
                    set(app.track_visual.Correlation(i),'visible','on');    
            end
        end
        
%         function plotTracking(app)
%             i = 1;
%             for channelNr = channels
%                 if app.tracking_results(channelNr).status == 'T'      
%                     
% %                     figure(channelNr +200);
% %                     clf(channelNr +200);
% %                     set(channelNr +200, 'Name', ['Channel ', num2str(channelNr), ...
% %                         ' (PRN ', ...
% %                         num2str(tracking_results(channelNr).PRN), ...
% %                         ') results']);
% %                     
% %                     %% Draw axes ======================================================
% %                     % Row 1
% %                     handles(1, 1) = subplot(3, 3, 1);
% %                     handles(1, 2) = subplot(3, 3, [2 3]);
% %                     % Row 2
% %                     handles(2, 1) = subplot(3, 3, 4);
% %                     handles(2, 2) = subplot(3, 3, [5 6]);
% %                     % Row 3
% %                     handles(3, 1) = subplot(3, 3, 7);
% %                     handles(3, 2) = subplot(3, 3, 8);
% %                     handles(3, 3) = subplot(3, 3, 9);
% %                     
% %                     %% Plot all figures ===============================================
% %                     
% %                     timeAxisInSeconds = (1:msToProcess)/1000;
% %                 
% %                     %----- Discrete-Time Scatter Plot ---------------------------------
% %                     plot(handles(1, 1), tracking_results(channelNr).I_P,...
% %                         tracking_results(channelNr).Q_P, ...
% %                         '.');
% %                     
% %                     grid  (handles(1, 1));
% %                     axis  (handles(1, 1), 'equal');
% %                     title (handles(1, 1), 'Discrete-Time Scatter Plot');
% %                     xlabel(handles(1, 1), 'I prompt');
% %                     ylabel(handles(1, 1), 'Q prompt');
% %                     
% %                     %----- Nav bits ---------------------------------------------------
% %                     plot  (handles(1, 2), timeAxisInSeconds, ...
% %                         tracking_results(channelNr).I_P);
% %                     
% %                     grid  (handles(1, 2));
% %                     title (handles(1, 2), 'Bits of the navigation message');
% %                     xlabel(handles(1, 2), 'Time (s)');
% %                     axis  (handles(1, 2), 'tight');
% %                     
% %                     %----- PLL discriminator unfiltered--------------------------------
% %                     plot  (handles(2, 1), timeAxisInSeconds, ...
% %                         tracking_results(channelNr).pllDiscr, 'r');
% %                     
% %                     grid  (handles(2, 1));
% %                     axis  (handles(2, 1), 'tight');
% %                     xlabel(handles(2, 1), 'Time (s)');
% %                     ylabel(handles(2, 1), 'Amplitude');
% %                     title (handles(2, 1), 'Raw PLL discriminator');
% %                     
% %                     %----- Correlation ------------------------------------------------
% %                     plot(handles(2, 2), timeAxisInSeconds, ...
% %                     [sqrt(tracking_results(channelNr).I_E.^2 + ...
% %                     tracking_results(channelNr).Q_E.^2)', ...
% %                     sqrt(tracking_results(channelNr).I_P.^2 + ...
% %                     tracking_results(channelNr).Q_P.^2)', ...
% %                     sqrt(tracking_results(channelNr).I_L.^2 + ...
% %                     tracking_results(channelNr).Q_L.^2)'], ...
% %                     '-*');
% %                     
% %                     grid  (handles(2, 2));
% %                     title (handles(2, 2), 'Correlation results');
% %                     xlabel(handles(2, 2), 'Time (s)');
% %                     axis  (handles(2, 2), 'tight');
% %                         
% %                     hLegend = legend(handles(2, 2), '$\sqrt{I_{E}^2 + Q_{E}^2}$', ...
% %                         '$\sqrt{I_{P}^2 + Q_{P}^2}$', ...
% %                         '$\sqrt{I_{L}^2 + Q_{L}^2}$');
% %                         
% %                     %set interpreter from tex to latex. This will draw \sqrt correctly
% %                     set(hLegend, 'Interpreter', 'Latex');
% %                         
% %                     %----- PLL discriminator filtered----------------------------------
% %                     plot  (handles(3, 1), timeAxisInSeconds, ...
% %                         tracking_results(channelNr).pllDiscrFilt, 'b');
% %                         
% %                     grid  (handles(3, 1));
% %                     axis  (handles(3, 1), 'tight');
% %                     xlabel(handles(3, 1), 'Time (s)');
% %                     ylabel(handles(3, 1), 'Amplitude');
% %                     title (handles(3, 1), 'Filtered PLL discriminator');
% %                         
% %                     %----- DLL discriminator unfiltered--------------------------------
% %                     plot  (handles(3, 2), timeAxisInSeconds, ...
% %                         tracking_results(channelNr).dllDiscr, 'r');
% %                         
% %                     grid  (handles(3, 2));
% %                     axis  (handles(3, 2), 'tight');
% %                     xlabel(handles(3, 2), 'Time (s)');
% %                     ylabel(handles(3, 2), 'Amplitude');
% %                     title (handles(3, 2), 'Raw DLL discriminator');
% %                         
% %                     %----- DLL discriminator filtered----------------------------------
% %                     plot  (handles(3, 3), timeAxisInSeconds, ...
% %                         tracking_results(channelNr).dllDiscrFilt, 'b');
% %                         
% %                     grid  (handles(3, 3));
% %                     axis  (handles(3, 3), 'tight');
% %                     xlabel(handles(3, 3), 'Time (s)');
% %                     ylabel(handles(3, 3), 'Amplitude');
% %                     title (handles(3, 3), 'Filtered DLL discriminator');
%                         
%                     %----- Plot CNo----------------------------------
                    figure(channelNr +300);
                    clf(app.track_visual.Cno_Graph(i), channelNr +300);
                    set(app.track_visual.Cno_Graph(i), channelNr +300, 'Name', ['Channel ', num2str(channelNr), ...
                        ' (PRN ', ...
                        num2str(app.tracking_results(channelNr).PRN), ...
                        ') CNo']);
                    plot(app.track_visual.Cno_Graph(i), app.tracking_results(channelNr).CNo.VSMValue);hold on;
                    plot(app.track_visual.Cno_Graph(i), app.tracking_results(channelNr).CNo.VSMValue);hold off;
                    title('CNo Estimation');
                    ylabel('dB-Hz');
                    xlabel('epoch computation');
%                     
%                     i = i + 1;
% 
%                 end %if trackResults(channelNr).status == 'T' 
%     
%             end % for channelNr = channelList
%         end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Equivalent to main function 
            
            set(app.run_IQ_button,'visible', 'off'); % Allows play button to only appear after the uploaded file
            set(app.run_acquisition_button,'visible', 'off') % Allow play button to appear
%             set(app.run_tracking_button,'visible', 'off') % Allow play button to appear

            app.generate_tracking_results();
%             app.track_tabs.Title = ['CH ' int2str(i)];

        end

        % Button pushed function: UPLOADIQFILEButton
        function UPLOADIQFILEButtonPushed(app, event)
             app.nav = NavTek;
            
             % Wait for iq_file to be uploaded
             if app.nav.fnameIQ
%                  app.nav.userfnameIQ(app.iq_file); % Update fnameIQ in NavTek class
%                  app.nav.fnameIQ = app.iq_file;
                 set(app.run_IQ_button,'visible', 'on') % Allow play button to appear
                 app.run_IQ_button.BackgroundColor = [0.00,1.00,0.00]; % For succesfull upload, the button turns green. 
             end   
        end

        % Image clicked function: run_IQ_button
        function run_IQ_buttonClicked(app, event)
            
%             disp(app.nav.fnameIQ)
            app.probeData(); % Plot IQ Characteristics
            
            app.TabGroup.SelectedTab = app.IQTab; % Opens the new tab for the IQ Characteristics
                 
            %% Make arrow visible in a bit
            set(app.run_acquisition_button,'visible', 'on') % Allow play button to appear
            app.run_acquisition_button.BackgroundColor = [0.00,1.00,0.00]; % Allow user to continue.

            
        end

        % Image clicked function: run_acquisition_button
        function run_acquisition_buttonImageClicked(app, event)
            
            app.fid = fopen(app.nav.fnameIQ, 'rb');
            
           if (app.fid > 0)
                %move to the start of the processing location
                fseek(app.fid, 2*app.nav.skipNumBytes, 'bof');

                %Find the number of samples per chipping sequence
                samples_per_code = round(app.nav.fsamp / (app.nav.fchip / app.nav.codeLength));

                %need to read in at least 42ms of data to get good course
                %aquisition
                codeLen = max(42, app.nav.NonCohTime + 2);
            
                %read the data to perform acquisition
                data = fread(app.fid, 2*codeLen*samples_per_code, app.nav.dataType)';
                data = data(1:2:end) + 1i .* data(2:2:end);

                disp("Aquiring satellites");
                %% Aquisition
                [acq_results, app.acq_results_visual, app.PRN_visual] = app.nav.aquisition(data, app.acq_results_visual, app.PRN_visual);
                
                if any(acq_results.carrFreq)
                    app.channel = app.nav.sortAcquisition(acq_results);
                    app.nav.dispFineCAResults(app.channel);

                else 
                    warning("No Satellites to track");
                    return;
                end
                %get rid of acquisiton plots to start tracking
                close all;
           end

           app.satellite_acquired_generate();
           app.TabGroup.SelectedTab = app.ACQUISITIONTab; % Opens the Tab for Acquisition
           
           %% Make arrow visible in a bit
           set(app.run_tracking_button,'visible', 'on') % Allow play button to appear
           app.run_acquisition_button.BackgroundColor = [0.00,1.00,0.00]; % Allow user to continue.


        end

        % Image clicked function: run_tracking_button
        function run_tracking_buttonImageClicked(app, event)
            app.fid = fopen(app.nav.fnameIQ, 'rb');

            app.generate_tracking_results();
            track_results = app.nav.tracking(app.fid, app.channel);
            app.nav.plotTracking(1:app.nav.numChannels, track_results, obj.msToProcess);
            app.TabGroup.SelectedTab = app.TRACKINGTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HOMETab = uitab(app.TabGroup);
            app.HOMETab.Title = 'HOME';
            app.WELCOMETOLOGPOSELabel = uilabel(app.HOMETab);
            app.WELCOMETOLOGPOSELabel.Text = 'WELCOME TO LOG POSE';
            app.StartbyuploadingaIQfileontherightLabel = uilabel(app.HOMETab);
            app.StartbyuploadingaIQfileontherightLabel.Text = 'Start by uploading a IQ file on the right.';
            app.ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel = uilabel(app.HOMETab);
            app.ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel.Text = 'A TOOL TO POST-PROCESS YOUR OWN POSITION.';
            app.UPLOADIQFILEButton = uibutton(app.HOMETab, 'push');
            app.UPLOADIQFILEButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADIQFILEButtonPushed, true);
            app.UPLOADIQFILEButton.Text = 'UPLOAD IQ FILE ';
            app.Image = uiimage(app.HOMETab);
            app.Image.ImageSource = 'car.png';
            app.run_IQ_button = uiimage(app.HOMETab);
            app.run_IQ_button.ImageClickedFcn = createCallbackFcn(app, @run_IQ_buttonClicked, true);
            app.run_IQ_button.ImageSource = 'play-button.png';
            app.IQTab = uitab(app.TabGroup);
            app.IQTab.Title = 'IQ';
            app.iq_frq = uiaxes(app.IQTab);
            title(app.iq_frq, 'Frequency domain plot')
            xlabel(app.iq_frq, 'Frequency (Mhz)')
            ylabel(app.iq_frq, 'Magnitude')
            zlabel(app.iq_frq, 'Z')
            app.time_Q = uiaxes(app.IQTab);
            title(app.time_Q, 'Time domain plot (Q)')
            xlabel(app.time_Q, 'Time (ms)')
            ylabel(app.time_Q, 'Amplitude')
            zlabel(app.time_Q, 'Z')
            app.time_I = uiaxes(app.IQTab);
            title(app.time_I, 'Time domain plot (I)')
            xlabel(app.time_I, 'Time (ms)')
            ylabel(app.time_I, 'Amplitude')
            zlabel(app.time_I, 'Z')
            app.hist_Q = uiaxes(app.IQTab);
            title(app.hist_Q, 'Histogram (Q)')
            xlabel(app.hist_Q, 'Bin')
            ylabel(app.hist_Q, 'Number in bin')
            zlabel(app.hist_Q, 'Z')
            app.hist_I = uiaxes(app.IQTab);
            title(app.hist_I, 'Histogram (I)')
            xlabel(app.hist_I, 'Bin')
            ylabel(app.hist_I, 'Number in bin')
            zlabel(app.hist_I, 'Z')
            app.IQCharacteristicsLabel = uilabel(app.IQTab);
            app.IQCharacteristicsLabel.Text = 'IQ Characteristics';
            app.run_acquisition_button = uiimage(app.IQTab);
            app.run_acquisition_button.ImageClickedFcn = createCallbackFcn(app, @run_acquisition_buttonImageClicked, true);
            app.run_acquisition_button.ImageSource = 'play-button.png';
            app.ACQUISITIONTab = uitab(app.TabGroup);
            app.ACQUISITIONTab.Title = 'ACQUISITION';
            app.SatAcq = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq, 'C/A for PRN')
            xlabel(app.SatAcq, 'f_d bin [Hz]')
            ylabel(app.SatAcq, 't_s bin [sec]')
            zlabel(app.SatAcq, 'Z')
            app.SatAcq_2 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_2, 'C/A for PRN')
            xlabel(app.SatAcq_2, 'f_d bin [Hz]')
            ylabel(app.SatAcq_2, 't_s bin [sec]')
            zlabel(app.SatAcq_2, 'Z')
            app.SatAcq_3 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_3, 'C/A for PRN')
            xlabel(app.SatAcq_3, 'f_d bin [Hz]')
            ylabel(app.SatAcq_3, 't_s bin [sec]')
            zlabel(app.SatAcq_3, 'Z')
            app.SatAcq_4 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_4, 'C/A for PRN')
            xlabel(app.SatAcq_4, 'f_d bin [Hz]')
            ylabel(app.SatAcq_4, 't_s bin [sec]')
            zlabel(app.SatAcq_4, 'Z')
            app.SatAcq_5 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_5, 'C/A for PRN')
            xlabel(app.SatAcq_5, 'f_d bin [Hz]')
            ylabel(app.SatAcq_5, 't_s bin [sec]')
            zlabel(app.SatAcq_5, 'Z')
            app.SatAcq_6 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_6, 'C/A for PRN')
            xlabel(app.SatAcq_6, 'f_d bin [Hz]')
            ylabel(app.SatAcq_6, 't_s bin [sec]')
            zlabel(app.SatAcq_6, 'Z')
            app.SatAcq_7 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_7, 'C/A for PRN')
            xlabel(app.SatAcq_7, 'f_d bin [Hz]')
            ylabel(app.SatAcq_7, 't_s bin [sec]')
            zlabel(app.SatAcq_7, 'Z')
            app.SatAcq_8 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_8, 'C/A for PRN')
            xlabel(app.SatAcq_8, 'f_d bin [Hz]')
            ylabel(app.SatAcq_8, 't_s bin [sec]')
            zlabel(app.SatAcq_8, 'Z')
            app.SatAcq_9 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_9, 'C/A for PRN')
            xlabel(app.SatAcq_9, 'f_d bin [Hz]')
            ylabel(app.SatAcq_9, 't_s bin [sec]')
            zlabel(app.SatAcq_9, 'Z')
            app.SatAcq_10 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_10, 'C/A for PRN')
            xlabel(app.SatAcq_10, 'f_d bin [Hz]')
            ylabel(app.SatAcq_10, 't_s bin [sec]')
            zlabel(app.SatAcq_10, 'Z')
            app.SatAcq_11 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_11, 'C/A for PRN')
            xlabel(app.SatAcq_11, 'f_d bin [Hz]')
            ylabel(app.SatAcq_11, 't_s bin [sec]')
            zlabel(app.SatAcq_11, 'Z')
            app.SatAcq_12 = uiaxes(app.ACQUISITIONTab);
            title(app.SatAcq_12, 'C/A for PRN')
            xlabel(app.SatAcq_12, 'f_d bin [Hz]')
            ylabel(app.SatAcq_12, 't_s bin [sec]')
            zlabel(app.SatAcq_12, 'Z')
            app.Best12SatelliteAcquisitionsLabel = uilabel(app.ACQUISITIONTab);
            app.Best12SatelliteAcquisitionsLabel.Text = 'Best 12 Satellite Acquisitions';
            app.run_tracking_button = uiimage(app.ACQUISITIONTab);
            app.run_tracking_button.ImageClickedFcn = createCallbackFcn(app, @run_tracking_buttonImageClicked, true);
            app.run_tracking_button.ImageSource = 'play-button.png';
            app.TRACKINGTab = uitab(app.TabGroup);
            app.TRACKINGTab.Title = 'TRACKING';
            app.SATELLITEPOSTab = uitab(app.TabGroup);
            app.SATELLITEPOSTab.Title = 'SATELLITE POS';
            app.USERPOSTab = uitab(app.TabGroup);
            app.USERPOSTab.Title = 'USER POS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logpose_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end